classdef CreateModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        GeneralTab                    matlab.ui.container.Tab
        SamplingRangemmPanel          matlab.ui.container.Panel
        SamplingRangeGauge            matlab.ui.control.LinearGauge
        MinEditFieldLabel             matlab.ui.control.Label
        SamplingRangeMinEditField     matlab.ui.control.NumericEditField
        MaxEditField_3Label           matlab.ui.control.Label
        SamplingRangeMaxEditField     matlab.ui.control.NumericEditField
        StideEditFieldLabel           matlab.ui.control.Label
        SamplingRangeStrideEditField  matlab.ui.control.NumericEditField
        AnglesdegreesPanel            matlab.ui.container.Panel
        MinEditField_2Label           matlab.ui.control.Label
        AnglesMinEditField            matlab.ui.control.NumericEditField
        MaxEditField_2Label           matlab.ui.control.Label
        AnglesMaxEditField            matlab.ui.control.NumericEditField
        StrideEditField_2Label        matlab.ui.control.Label
        AnglesStrideEditField         matlab.ui.control.NumericEditField
        AnglesGauge                   matlab.ui.control.NinetyDegreeGauge
        DensityRangemgccPanel         matlab.ui.container.Panel
        DensityRangeGauge             matlab.ui.control.LinearGauge
        MinEditField_3Label           matlab.ui.control.Label
        DensityRangeMinEditField      matlab.ui.control.NumericEditField
        MaxEditField_4Label           matlab.ui.control.Label
        DensityRangeMaxEditField      matlab.ui.control.NumericEditField
        StideEditField_2Label         matlab.ui.control.Label
        DensityRangeStrideEditField   matlab.ui.control.NumericEditField
        MetaPanel                     matlab.ui.container.Panel
        NameLabel                     matlab.ui.control.Label
        NameEditField                 matlab.ui.control.EditField
        DescriptionoptionalTextAreaLabel  matlab.ui.control.Label
        DescriptionoptionalTextArea   matlab.ui.control.TextArea
        AuthorLabel                   matlab.ui.control.Label
        AuthorEditField               matlab.ui.control.EditField
        ResolutionmmPanel             matlab.ui.container.Panel
        KernelSigmaEditFieldLabel     matlab.ui.control.Label
        KernelSigmaEditField          matlab.ui.control.NumericEditField
        SliceSpacingEditFieldLabel    matlab.ui.control.Label
        SliceSpacingEditField         matlab.ui.control.NumericEditField
        VOIsTab                       matlab.ui.container.Tab
        ListBox                       matlab.ui.control.ListBox
        AddButton                     matlab.ui.control.Button
        RemoveButton                  matlab.ui.control.Button
        ParametersPanel               matlab.ui.container.Panel
        SoftTissueBMDmgccPanel        matlab.ui.container.Panel
        MeanEditFieldLabel            matlab.ui.control.Label
        Mean0EditField                matlab.ui.control.NumericEditField
        StdDevEditFieldLabel          matlab.ui.control.Label
        StdDev0EditField              matlab.ui.control.NumericEditField
        CortexBMDmgccPanel            matlab.ui.container.Panel
        MeanEditField_2Label          matlab.ui.control.Label
        Mean1EditField                matlab.ui.control.NumericEditField
        StdDevEditField_2Label        matlab.ui.control.Label
        StdDev1EditField              matlab.ui.control.NumericEditField
        MarrowBMDmgccPanel            matlab.ui.container.Panel
        MeanEditField_3Label          matlab.ui.control.Label
        Mean2EditField                matlab.ui.control.NumericEditField
        StdDevEditField_3Label        matlab.ui.control.Label
        StdDev2EditField              matlab.ui.control.NumericEditField
        CortexWidthPanel              matlab.ui.container.Panel
        MeanEditField_4Label          matlab.ui.control.Label
        CortMeanEditField             matlab.ui.control.NumericEditField
        StdDevEditField_4Label        matlab.ui.control.Label
        CortStdDevEditField           matlab.ui.control.NumericEditField
        WPDFAxis                      matlab.ui.control.UIAxes
        MetaPanel_2                   matlab.ui.container.Panel
        NameEditField_2Label          matlab.ui.control.Label
        VOINameEditField              matlab.ui.control.EditField
        LabelEditFieldLabel           matlab.ui.control.Label
        VOILabelEditField             matlab.ui.control.NumericEditField
        GenerateModelButton           matlab.ui.control.Button
        CancelButton                  matlab.ui.control.Button
        GeneratingModelLabel          matlab.ui.control.Label
        SaveModelButton               matlab.ui.control.Button
    end


    properties (Access = private)
        VOIs % VOIs
        allowClose % Description
        model % Generated model
    end

    methods (Access = private)
    
        function updateAnglesGauge(app)
            limits = [app.AnglesMinEditField.Value app.AnglesMaxEditField.Value];
            stride = app.AnglesStrideEditField.Value;
            app.AnglesGauge.Limits = limits;
            app.AnglesGauge.Value = limits(1);
            app.AnglesGauge.MajorTicksMode = 'auto';
            app.AnglesGauge.MinorTicks = limits(1):stride:limits(2);
        end
        
        function updateSamplingRangeGauge(app)
            limits = [app.SamplingRangeMinEditField.Value, app.SamplingRangeMaxEditField.Value];
            stride = app.SamplingRangeStrideEditField.Value;
            app.SamplingRangeGauge.Limits = limits;
            app.SamplingRangeGauge.Value = 0;
            app.SamplingRangeGauge.MinorTicks = limits(1):stride:limits(2);
        end
        
        function updateDensityRangeGauge(app)
            limits = [app.DensityRangeMinEditField.Value, app.DensityRangeMaxEditField.Value];
            stride = app.DensityRangeStrideEditField.Value;
            app.DensityRangeGauge.Limits = limits;
            app.DensityRangeGauge.Value = 0;
            app.DensityRangeGauge.MinorTicks = limits(1):stride:limits(2);
        end
        
        function setVOIParameterPanelEnabledState(app, state)
            app.Mean0EditField.Enable = state;
            app.StdDev0EditField.Enable = state;
            app.Mean1EditField.Enable = state;
            app.StdDev1EditField.Enable = state;
            app.Mean2EditField.Enable = state;
            app.StdDev2EditField.Enable = state;
            app.CortMeanEditField.Enable = state;
            app.CortStdDevEditField.Enable = state;
        end
        
        function updateVOIUI(app, varargin)
            
            if isempty(varargin)
                voi = app.selectedVOI;
            else
                voi = varargin{1};
            end
            
            app.Mean0EditField.Value = voi.mu(1);
            app.StdDev0EditField.Value = voi.sigma(1);
            app.Mean1EditField.Value = voi.mu(2);
            app.StdDev1EditField.Value = voi.sigma(2);
            app.Mean2EditField.Value = voi.mu(3);
            app.StdDev2EditField.Value = voi.sigma(3);
            
            app.CortMeanEditField.Value = exp(voi.muW)*2;
            app.CortStdDevEditField.Value = exp(voi.sigmaW);
            
            app.VOINameEditField.Value = voi.name;
            app.VOILabelEditField.Value = voi.label;
            
            % update list box
            for idx=1:length(app.ListBox.ItemsData)
                if strcmp(voi.uuid, app.ListBox.ItemsData{idx})
                    app.ListBox.Items{idx} = voi.name;
                end
            end
            
            upper99 = logninv([0.99], voi.muW + log(2), voi.sigmaW);
            
            t = linspace(0, upper99, 1000);
            f = lognpdf(t, voi.muW + log(2), voi.sigmaW);
            area(app.WPDFAxis, t, f);
            
            ci = logninv([0.05, 0.95], voi.muW + log(2), voi.sigmaW);
            tci = linspace(ci(1), ci(2), 1000);
            fci = lognpdf(tci, voi.muW + log(2), voi.sigmaW);
            hold(app.WPDFAxis, 'on');
            area(app.WPDFAxis, tci, fci, 'FaceAlpha', 1.0, 'FaceColor', 'r');
            hold(app.WPDFAxis, 'off');
            
            
        end
        
        function voi = selectedVOI(app)
            uuid = app.ListBox.Value;
            
            for voi=app.VOIs
                if strcmp(voi.uuid, uuid)
                    return;
                end
            end
        end
        
        function updateVOIDataFromUI(app)
            
            if isempty(app.ListBox.Value)
                return
            end
            
            VOI = struct;
            VOI.name = app.VOINameEditField.Value;
            VOI.label = app.VOILabelEditField.Value;
            VOI.uuid = app.ListBox.Value;
            VOI.mu = [app.Mean0EditField.Value, app.Mean1EditField.Value, app.Mean2EditField.Value];
            VOI.sigma = [app.StdDev0EditField.Value, app.StdDev1EditField.Value, app.StdDev2EditField.Value];
            VOI.muW = log(app.CortMeanEditField.Value) - log(2);
            VOI.sigmaW = log(app.CortStdDevEditField.Value);
            
            index = 1;
            for voi=app.VOIs
                if strcmp(voi.uuid, VOI.uuid)
                    app.VOIs(index) = VOI;
                    break
                end
                index = index + 1;
            end
        end

        function validateModel(app)
            
            if ~isempty(app.NameEditField.Value) && ...
               ~isempty(app.AuthorEditField.Value) && ...
               ~isempty(app.VOIs)
                app.GenerateModelButton.Enable = 'on';
            else
                app.GenerateModelButton.Enable = 'off';
            end
            
        end
        
        function generateModel(app)
            
            app.model = struct;
            app.model.name = app.NameEditField.Value;
            app.model.author = app.AuthorEditField.Value;
            app.model.version = '1.0.0';
            app.model.creationDate = datestr(now, 30);
            app.model.description = app.DescriptionoptionalTextArea.Value;
            app.model.samplingRange = struct;
            app.model.samplingRange.min = app.SamplingRangeMinEditField.Value;
            app.model.samplingRange.max = app.SamplingRangeMaxEditField.Value;
            app.model.samplingRange.stride = app.SamplingRangeStrideEditField.Value;
            app.model.angleRange = struct;
            app.model.angleRange.min = app.AnglesMinEditField.Value;
            app.model.angleRange.max = app.AnglesMaxEditField.Value;
            app.model.angleRange.stride = app.AnglesStrideEditField.Value;
            app.model.densityRange = struct;
            app.model.densityRange.min = app.DensityRangeMinEditField.Value;
            app.model.densityRange.max = app.DensityRangeMaxEditField.Value;
            app.model.densityRange.stride = app.DensityRangeStrideEditField.Value;
            app.model.kernel = struct;
            app.model.kernel.sigma = app.KernelSigmaEditField.Value;
            app.model.sliceSpacing = app.SliceSpacingEditField.Value;
            app.model.VOIs = {};
            
            t = app.model.samplingRange.min:app.model.samplingRange.stride:app.model.samplingRange.max;
            theta = app.model.angleRange.min:app.model.angleRange.stride:app.model.angleRange.max;
            z = app.model.densityRange.min:app.model.densityRange.stride:app.model.densityRange.max;
            
            theta = theta * pi / 180;
            
            [T, Theta, Z] = meshgrid(t, theta, z);
            
            X = [T(:), Theta(:), Z(:)];
            
            GTable = CortidQCT.genGTable(app.model.kernel.sigma, app.model.sliceSpacing);
            
            progress = waitbar(0, sprintf('Computing PDF for VOI "%s"', app.VOIs(1).name), ...
                'Name', 'Generating Model', ...
                'WindowStyle','modal', ...
                'CreateCancelBtn', ...
                'setappdata(gcbf,''canceling'',1)');
            
            completed = true;
            index = 0;
            for voi=app.VOIs
                
                waitbar(index / length(app.VOIs), progress, sprintf('Computing PDF for VOI "%s"', voi.name));
                
                if getappdata(progress,'canceling')
                    completed = false;
                    break
                end

                lVOI = struct;
                lVOI.name = voi.name;
                lVOI.label = voi.label;
                lVOI.mean = voi.mu;
                lVOI.std = voi.sigma;
                lVOI.muW = voi.muW;
                lVOI.sigmaW = voi.sigmaW;

                f = CortidQCT.evalPDF(X, voi.mu, voi.sigma, voi.muW, voi.sigmaW, GTable);

                F = reshape(f, size(T));
                
                F = permute(F, [2, 3, 1]);
                
                lVOI.scale = max(F(:));
                lVOI.data = F / max(F(:));
                
                app.model.VOIs{end + 1} = lVOI;

                index = index + 1;
            end

            waitbar(1, progress, 'Done.');

            delete(progress);

            app.allowClose = true;
            app.GeneratingModelLabel.Visible = 'off';
            
            if completed
                app.GenerateModelButton.Visible = 'off';
                app.SaveModelButton.Visible = 'on';
                app.SaveModelButton.Enable = 'on';
                
                assignin('base', 'GeneratedModel', app.model);
            end

        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.updateAnglesGauge;
            app.updateSamplingRangeGauge;
            app.updateDensityRangeGauge;
            app.setVOIParameterPanelEnabledState('off');
            app.validateModel;
            app.allowClose = true;
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.delete
        end

        % Value changed function: AnglesMinEditField
        function AnglesMinEditFieldValueChanged(app, event)
           app.updateAnglesGauge;
           app.validateModel;
        end

        % Value changed function: AnglesMaxEditField
        function AnglesMaxEditFieldValueChanged(app, event)
           app.updateAnglesGauge;
           app.validateModel;
        end

        % Value changed function: AnglesStrideEditField
        function AnglesStrideEditFieldValueChanged(app, event)
            app.updateAnglesGauge;
            app.validateModel;
        end

        % Value changed function: SamplingRangeMinEditField
        function SamplingRangeMinEditFieldValueChanged(app, event)
            app.updateSamplingRangeGauge;
            app.validateModel;
        end

        % Value changed function: SamplingRangeMaxEditField
        function SamplingRangeMaxEditFieldValueChanged(app, event)
            app.updateSamplingRangeGauge;
            app.validateModel;
        end

        % Value changed function: SamplingRangeStrideEditField
        function SamplingRangeStrideEditFieldValueChanged(app, event)
            app.updateSamplingRangeGauge;
            app.validateModel;
        end

        % Value changed function: DensityRangeMinEditField
        function DensityRangeMinEditFieldValueChanged(app, event)
            app.updateDensityRangeGauge;
            app.validateModel;
        end

        % Value changed function: DensityRangeMaxEditField
        function DensityRangeMaxEditFieldValueChanged(app, event)
            app.updateDensityRangeGauge;
            app.validateModel;
        end

        % Value changed function: DensityRangeStrideEditField
        function DensityRangeStrideEditFieldValueChanged(app, event)
            app.updateDensityRangeGauge;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if selectedTab == app.VOIsTab
                app.setVOIParameterPanelEnabledState('off');
            end
            app.validateModel;
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
            if isempty(value)
                app.setVOIParameterPanelEnabledState('off');
                app.RemoveButton.Enable = 'off';
                return;
            end
            
            for voi=app.VOIs
                if strcmp(voi.uuid, value)
                    app.updateVOIUI(voi);
                    app.setVOIParameterPanelEnabledState('On');
                    app.RemoveButton.Enable = 'On';
                    
                    app.VOINameEditField.Value = voi.name;
                    app.VOILabelEditField.Value = voi.label;
                    
                    break;
                end
            end
            app.validateModel;
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            VOI = struct;
            VOI.name = app.VOINameEditField.Value;
            VOI.label = app.VOILabelEditField.Value;
            VOI.uuid = char(java.util.UUID.randomUUID);
            VOI.mu = [0, 1200, 80];
            VOI.sigma = [100, 200, 100];
            VOI.muW = log(0.4) - log(2);
            VOI.sigmaW = log(1.5);
            if isempty(VOI.name) || ~isempty(app.ListBox.Value)
                VOI.name = 'unnamed';
            end
            
            app.VOIs = [app.VOIs VOI];
            data = app.ListBox.ItemsData;
            items = app.ListBox.Items;
            items{end+1} = VOI.name;
            data{end+1} = VOI.uuid;
            
            app.ListBox.Items = items;
            app.ListBox.ItemsData = data;
            
            app.ListBox.Value = VOI.uuid;
            app.ListBox.ValueChangedFcn(app, []);
            app.validateModel;
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            value = app.ListBox.Value;
            
            idx = 0;
            
            for voi=app.VOIs
                idx = idx + 1;
                if strcmp(voi.uuid, value)
                    break
                end
            end
            
            app.ListBox.Items = app.ListBox.Items([1:idx-1, idx+1:end]);
            app.ListBox.ItemsData = app.ListBox.ItemsData([1:idx-1, idx+1:end]);
            app.VOIs = app.VOIs([1:idx-1, idx+1:end]);
            
            if ~isempty(app.ListBox.ItemsData)
                app.ListBox.Value = app.ListBox.ItemsData{end};
            else
                app.ListBox.Value = {};
            end
            app.setVOIParameterPanelEnabledState('off');
            
            app.ListBoxValueChanged([]);
            app.validateModel;
        end

        % Value changed function: CortMeanEditField
        function CortMeanEditFieldValueChanged(app, event)
            app.updateVOIDataFromUI;
            app.updateVOIUI;
            app.validateModel;
        end

        % Value changed function: CortStdDevEditField
        function CortStdDevEditFieldValueChanged(app, event)
            app.updateVOIDataFromUI;
            app.updateVOIUI;
            app.validateModel;
        end

        % Value changed function: Mean0EditField
        function Mean0EditFieldValueChanged(app, event)
            app.updateVOIDataFromUI;
            app.validateModel;
        end

        % Value changed function: StdDev0EditField
        function StdDev0EditFieldValueChanged(app, event)
            app.updateVOIDataFromUI;
            app.validateModel;
        end

        % Value changed function: Mean1EditField
        function Mean1EditFieldValueChanged(app, event)
            app.updateVOIDataFromUI;
            app.validateModel;
        end

        % Value changed function: StdDev1EditField
        function StdDev1EditFieldValueChanged(app, event)
            app.updateVOIDataFromUI;
            app.validateModel;
        end

        % Value changed function: Mean2EditField
        function Mean2EditFieldValueChanged(app, event)
            app.updateVOIDataFromUI;
            app.validateModel;
        end

        % Value changed function: StdDev2EditField
        function StdDev2EditFieldValueChanged(app, event)
            app.updateVOIDataFromUI;
            app.validateModel;
        end

        % Value changed function: VOINameEditField
        function VOINameEditFieldValueChanged(app, event)
            app.updateVOIDataFromUI;
            if ~isempty(app.ListBox.Value)
                app.updateVOIUI;
            end
            app.validateModel;
        end

        % Value changed function: VOILabelEditField
        function VOILabelEditFieldValueChanged(app, event)
            app.updateVOIDataFromUI;
            app.validateModel;
        end

        % Value changed function: DescriptionoptionalTextArea
        function DescriptionoptionalTextAreaValueChanged(app, event)
            app.validateModel;
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
            app.validateModel;
        end

        % Value changed function: AuthorEditField
        function AuthorEditFieldValueChanged(app, event)
            app.validateModel;
        end

        % Button pushed function: GenerateModelButton
        function GenerateModelButtonPushed(app, event)
            app.GenerateModelButton.Enable = 'off';
%             app.UIFigure.Visible = 'off';

            app.GeneratingModelLabel.Visible = 'on';
            app.allowClose = false;
            app.generateModel;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.allowClose
                app.delete;
            end
        end

        % Button pushed function: SaveModelButton
        function SaveModelButtonPushed(app, event)
            filter = {'*.yml'; '*.yaml'; '*.*'};
            [file, path] = uiputfile(filter, 'Save Measurement Model', [app.model.name, '.yml']);
            if file ~= 0
                CortidQCT.saveModel(app.model, [path file]);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.SamplingRangemmPanel = uipanel(app.GeneralTab);
            app.SamplingRangemmPanel.Title = 'Sampling Range [mm]';
            app.SamplingRangeGauge = uigauge(app.SamplingRangemmPanel, 'linear');
            app.SamplingRangeGauge.Limits = [-5 5];
            app.SamplingRangeGauge.MinorTicks = [-5 -4.5 -4 -3.5 -3 -2.5 -2 -1.5 -1 -0.5 0 0.5 1 1.5 2 2.5 3 3.5 4 4.5 5];
            app.MinEditFieldLabel = uilabel(app.SamplingRangemmPanel);
            app.MinEditFieldLabel.Text = 'Min';
            app.SamplingRangeMinEditField = uieditfield(app.SamplingRangemmPanel, 'numeric');
            app.SamplingRangeMinEditField.Limits = [-10 0];
            app.SamplingRangeMinEditField.ValueDisplayFormat = '%.1f';
            app.SamplingRangeMinEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRangeMinEditFieldValueChanged, true);
            app.SamplingRangeMinEditField.Value = -2;
            app.MaxEditField_3Label = uilabel(app.SamplingRangemmPanel);
            app.MaxEditField_3Label.Text = 'Max';
            app.SamplingRangeMaxEditField = uieditfield(app.SamplingRangemmPanel, 'numeric');
            app.SamplingRangeMaxEditField.Limits = [0 10];
            app.SamplingRangeMaxEditField.ValueDisplayFormat = '%.1f';
            app.SamplingRangeMaxEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRangeMaxEditFieldValueChanged, true);
            app.SamplingRangeMaxEditField.Value = 2;
            app.StideEditFieldLabel = uilabel(app.SamplingRangemmPanel);
            app.StideEditFieldLabel.Text = 'Stide';
            app.SamplingRangeStrideEditField = uieditfield(app.SamplingRangemmPanel, 'numeric');
            app.SamplingRangeStrideEditField.Limits = [0 10];
            app.SamplingRangeStrideEditField.ValueDisplayFormat = '%.3f';
            app.SamplingRangeStrideEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRangeStrideEditFieldValueChanged, true);
            app.SamplingRangeStrideEditField.Value = 0.05;
            app.AnglesdegreesPanel = uipanel(app.GeneralTab);
            app.AnglesdegreesPanel.Title = 'Angles [degrees]';
            app.MinEditField_2Label = uilabel(app.AnglesdegreesPanel);
            app.MinEditField_2Label.Text = 'Min';
            app.AnglesMinEditField = uieditfield(app.AnglesdegreesPanel, 'numeric');
            app.AnglesMinEditField.Limits = [0 90];
            app.AnglesMinEditField.ValueChangedFcn = createCallbackFcn(app, @AnglesMinEditFieldValueChanged, true);
            app.MaxEditField_2Label = uilabel(app.AnglesdegreesPanel);
            app.MaxEditField_2Label.Text = 'Max';
            app.AnglesMaxEditField = uieditfield(app.AnglesdegreesPanel, 'numeric');
            app.AnglesMaxEditField.Limits = [0 90];
            app.AnglesMaxEditField.ValueChangedFcn = createCallbackFcn(app, @AnglesMaxEditFieldValueChanged, true);
            app.AnglesMaxEditField.Value = 90;
            app.StrideEditField_2Label = uilabel(app.AnglesdegreesPanel);
            app.StrideEditField_2Label.Text = 'Stride';
            app.AnglesStrideEditField = uieditfield(app.AnglesdegreesPanel, 'numeric');
            app.AnglesStrideEditField.Limits = [0 90];
            app.AnglesStrideEditField.ValueChangedFcn = createCallbackFcn(app, @AnglesStrideEditFieldValueChanged, true);
            app.AnglesStrideEditField.Value = 5;
            app.AnglesGauge = uigauge(app.AnglesdegreesPanel, 'ninetydegree');
            app.AnglesGauge.Limits = [0 90];
            app.AnglesGauge.MinorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90];
            app.DensityRangemgccPanel = uipanel(app.GeneralTab);
            app.DensityRangemgccPanel.Title = 'Density Range [mg/cc]';
            app.DensityRangeGauge = uigauge(app.DensityRangemgccPanel, 'linear');
            app.DensityRangeGauge.Limits = [-1000 2000];
            app.DensityRangeGauge.MinorTicks = [-1000 -900 -800 -700 -600 -500 -400 -300 -200 -100 0 100 200 300 400 500 600 700 800 900 1000 1100 1200 1300 1400 1500 1600 1700 1800 1900 2000];
            app.MinEditField_3Label = uilabel(app.DensityRangemgccPanel);
            app.MinEditField_3Label.Text = 'Min';
            app.DensityRangeMinEditField = uieditfield(app.DensityRangemgccPanel, 'numeric');
            app.DensityRangeMinEditField.Limits = [-1000 3000];
            app.DensityRangeMinEditField.ValueDisplayFormat = '%.0f';
            app.DensityRangeMinEditField.ValueChangedFcn = createCallbackFcn(app, @DensityRangeMinEditFieldValueChanged, true);
            app.DensityRangeMinEditField.Value = -1000;
            app.MaxEditField_4Label = uilabel(app.DensityRangemgccPanel);
            app.MaxEditField_4Label.Text = 'Max';
            app.DensityRangeMaxEditField = uieditfield(app.DensityRangemgccPanel, 'numeric');
            app.DensityRangeMaxEditField.Limits = [-1000 3000];
            app.DensityRangeMaxEditField.ValueDisplayFormat = '%.0f';
            app.DensityRangeMaxEditField.ValueChangedFcn = createCallbackFcn(app, @DensityRangeMaxEditFieldValueChanged, true);
            app.DensityRangeMaxEditField.Value = 2000;
            app.StideEditField_2Label = uilabel(app.DensityRangemgccPanel);
            app.StideEditField_2Label.Text = 'Stide';
            app.DensityRangeStrideEditField = uieditfield(app.DensityRangemgccPanel, 'numeric');
            app.DensityRangeStrideEditField.Limits = [0 1000];
            app.DensityRangeStrideEditField.ValueDisplayFormat = '%.0f';
            app.DensityRangeStrideEditField.ValueChangedFcn = createCallbackFcn(app, @DensityRangeStrideEditFieldValueChanged, true);
            app.DensityRangeStrideEditField.Value = 1;
            app.MetaPanel = uipanel(app.GeneralTab);
            app.MetaPanel.Title = 'Meta';
            app.NameLabel = uilabel(app.MetaPanel);
            app.NameLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.MetaPanel, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.DescriptionoptionalTextAreaLabel = uilabel(app.MetaPanel);
            app.DescriptionoptionalTextAreaLabel.Text = 'Description (optional)';
            app.DescriptionoptionalTextArea = uitextarea(app.MetaPanel);
            app.DescriptionoptionalTextArea.ValueChangedFcn = createCallbackFcn(app, @DescriptionoptionalTextAreaValueChanged, true);
            app.AuthorLabel = uilabel(app.MetaPanel);
            app.AuthorLabel.Text = 'Author';
            app.AuthorEditField = uieditfield(app.MetaPanel, 'text');
            app.AuthorEditField.ValueChangedFcn = createCallbackFcn(app, @AuthorEditFieldValueChanged, true);
            app.ResolutionmmPanel = uipanel(app.GeneralTab);
            app.ResolutionmmPanel.Title = 'Resolution [mm]';
            app.KernelSigmaEditFieldLabel = uilabel(app.ResolutionmmPanel);
            app.KernelSigmaEditFieldLabel.Text = 'Kernel Sigma';
            app.KernelSigmaEditField = uieditfield(app.ResolutionmmPanel, 'numeric');
            app.KernelSigmaEditField.Limits = [0 Inf];
            app.KernelSigmaEditField.ValueDisplayFormat = '%.3f';
            app.KernelSigmaEditField.Value = 0.48;
            app.SliceSpacingEditFieldLabel = uilabel(app.ResolutionmmPanel);
            app.SliceSpacingEditFieldLabel.Text = 'Slice Spacing';
            app.SliceSpacingEditField = uieditfield(app.ResolutionmmPanel, 'numeric');
            app.SliceSpacingEditField.Limits = [0 Inf];
            app.SliceSpacingEditField.ValueDisplayFormat = '%.3f';
            app.SliceSpacingEditField.Value = 0.4;
            app.VOIsTab = uitab(app.TabGroup);
            app.VOIsTab.Title = 'VOIs';
            app.ListBox = uilistbox(app.VOIsTab);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.AddButton = uibutton(app.VOIsTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.VOIsTab, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Enable = 'off';
            app.RemoveButton.Text = 'Remove';
            app.ParametersPanel = uipanel(app.VOIsTab);
            app.ParametersPanel.Title = 'Parameters';
            app.SoftTissueBMDmgccPanel = uipanel(app.ParametersPanel);
            app.SoftTissueBMDmgccPanel.Title = 'Soft Tissue BMD [mg/cc]';
            app.MeanEditFieldLabel = uilabel(app.SoftTissueBMDmgccPanel);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.Mean0EditField = uieditfield(app.SoftTissueBMDmgccPanel, 'numeric');
            app.Mean0EditField.ValueDisplayFormat = '%.1f';
            app.Mean0EditField.ValueChangedFcn = createCallbackFcn(app, @Mean0EditFieldValueChanged, true);
            app.StdDevEditFieldLabel = uilabel(app.SoftTissueBMDmgccPanel);
            app.StdDevEditFieldLabel.Text = 'StdDev.';
            app.StdDev0EditField = uieditfield(app.SoftTissueBMDmgccPanel, 'numeric');
            app.StdDev0EditField.ValueDisplayFormat = '%.1f';
            app.StdDev0EditField.ValueChangedFcn = createCallbackFcn(app, @StdDev0EditFieldValueChanged, true);
            app.StdDev0EditField.Value = 100;
            app.CortexBMDmgccPanel = uipanel(app.ParametersPanel);
            app.CortexBMDmgccPanel.Title = 'Cortex BMD [mg/cc]';
            app.MeanEditField_2Label = uilabel(app.CortexBMDmgccPanel);
            app.MeanEditField_2Label.Text = 'Mean';
            app.Mean1EditField = uieditfield(app.CortexBMDmgccPanel, 'numeric');
            app.Mean1EditField.ValueDisplayFormat = '%.1f';
            app.Mean1EditField.ValueChangedFcn = createCallbackFcn(app, @Mean1EditFieldValueChanged, true);
            app.StdDevEditField_2Label = uilabel(app.CortexBMDmgccPanel);
            app.StdDevEditField_2Label.Text = 'StdDev.';
            app.StdDev1EditField = uieditfield(app.CortexBMDmgccPanel, 'numeric');
            app.StdDev1EditField.ValueDisplayFormat = '%.1f';
            app.StdDev1EditField.ValueChangedFcn = createCallbackFcn(app, @StdDev1EditFieldValueChanged, true);
            app.StdDev1EditField.Value = 100;
            app.MarrowBMDmgccPanel = uipanel(app.ParametersPanel);
            app.MarrowBMDmgccPanel.Title = 'Marrow BMD [mg/cc]';
            app.MeanEditField_3Label = uilabel(app.MarrowBMDmgccPanel);
            app.MeanEditField_3Label.Text = 'Mean';
            app.Mean2EditField = uieditfield(app.MarrowBMDmgccPanel, 'numeric');
            app.Mean2EditField.ValueDisplayFormat = '%.1f';
            app.Mean2EditField.ValueChangedFcn = createCallbackFcn(app, @Mean2EditFieldValueChanged, true);
            app.StdDevEditField_3Label = uilabel(app.MarrowBMDmgccPanel);
            app.StdDevEditField_3Label.Text = 'StdDev.';
            app.StdDev2EditField = uieditfield(app.MarrowBMDmgccPanel, 'numeric');
            app.StdDev2EditField.ValueDisplayFormat = '%.1f';
            app.StdDev2EditField.ValueChangedFcn = createCallbackFcn(app, @StdDev2EditFieldValueChanged, true);
            app.StdDev2EditField.Value = 100;
            app.CortexWidthPanel = uipanel(app.ParametersPanel);
            app.CortexWidthPanel.Title = 'Cortex Width';
            app.MeanEditField_4Label = uilabel(app.CortexWidthPanel);
            app.MeanEditField_4Label.Text = 'Mean';
            app.CortMeanEditField = uieditfield(app.CortexWidthPanel, 'numeric');
            app.CortMeanEditField.Limits = [0 Inf];
            app.CortMeanEditField.ValueDisplayFormat = '%.1f';
            app.CortMeanEditField.ValueChangedFcn = createCallbackFcn(app, @CortMeanEditFieldValueChanged, true);
            app.CortMeanEditField.Value = 0.2;
            app.StdDevEditField_4Label = uilabel(app.CortexWidthPanel);
            app.StdDevEditField_4Label.Text = 'StdDev.';
            app.CortStdDevEditField = uieditfield(app.CortexWidthPanel, 'numeric');
            app.CortStdDevEditField.ValueDisplayFormat = '%.1f';
            app.CortStdDevEditField.ValueChangedFcn = createCallbackFcn(app, @CortStdDevEditFieldValueChanged, true);
            app.CortStdDevEditField.Value = 1.5;
            app.WPDFAxis = uiaxes(app.ParametersPanel);
            app.MetaPanel_2 = uipanel(app.VOIsTab);
            app.MetaPanel_2.Title = 'Meta';
            app.NameEditField_2Label = uilabel(app.MetaPanel_2);
            app.NameEditField_2Label.Text = 'Name';
            app.VOINameEditField = uieditfield(app.MetaPanel_2, 'text');
            app.VOINameEditField.ValueChangedFcn = createCallbackFcn(app, @VOINameEditFieldValueChanged, true);
            app.LabelEditFieldLabel = uilabel(app.MetaPanel_2);
            app.VOILabelEditField = uieditfield(app.MetaPanel_2, 'numeric');
            app.VOILabelEditField.ValueDisplayFormat = '%.0f';
            app.VOILabelEditField.ValueChangedFcn = createCallbackFcn(app, @VOILabelEditFieldValueChanged, true);
            app.GenerateModelButton = uibutton(app.UIFigure, 'push');
            app.GenerateModelButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateModelButtonPushed, true);
            app.GenerateModelButton.Enable = 'off';
            app.GenerateModelButton.Text = 'Generate Model';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.GeneratingModelLabel = uilabel(app.UIFigure);
            app.GeneratingModelLabel.Enable = 'off';
            app.GeneratingModelLabel.Visible = 'off';
            app.GeneratingModelLabel.Text = 'Generating Model...';
            app.SaveModelButton = uibutton(app.UIFigure, 'push');
            app.SaveModelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveModelButtonPushed, true);
            app.SaveModelButton.Enable = 'off';
            app.SaveModelButton.Visible = 'off';
            app.SaveModelButton.Text = 'Save Model';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CreateModel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end